classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        FxController                 matlab.ui.container.Panel
        Delete                       matlab.ui.control.Button
        Next                         matlab.ui.control.Button
        Previous                     matlab.ui.control.Button
        Save                         matlab.ui.control.Button
        FunctionName                 matlab.ui.control.TextArea
        NameTextAreaLabel            matlab.ui.control.Label
        DegreeDDM                    matlab.ui.control.DropDown
        DegreeofpolynomialDropDownLabel  matlab.ui.control.Label
        ClearBTN                     matlab.ui.control.Button
        ImportBTN                    matlab.ui.control.Button
        RefreshBTN                   matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        PointSPN                     matlab.ui.control.Spinner
        SpinnerLabel                 matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        OpenLogBTN                   matlab.ui.control.Button
        Export                       matlab.ui.container.Panel
        toPDF                        matlab.ui.control.Button
        toOO                         matlab.ui.control.Button
        toEXCEL                      matlab.ui.control.Button
        FunctionList                 matlab.ui.control.TextArea
        SavedfunctionsTextAreaLabel  matlab.ui.control.Label
        FunctionsBTN                 matlab.ui.container.ButtonGroup
        All                          matlab.ui.control.RadioButton
        Current                      matlab.ui.control.RadioButton
        Prompt                       matlab.ui.control.TextArea
        PromptTextAreaLabel          matlab.ui.control.Label
        Show                         matlab.ui.container.ButtonGroup
        PointsBTN                    matlab.ui.control.RadioButton
        CurveBTN                     matlab.ui.control.RadioButton
        BothBTN                      matlab.ui.control.RadioButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

% ////////////////////////////////////////////////////////////////////////
% //////////////////////     GLOBAL PROPERITIES     //////////////////////
% ////////////////////////////////////////////////////////////////////////

    properties (Access = public)
        function_database = [];     % Array for saving functions structures
        selected_function = 1;      % Function selection index
        show_option = 'CP';         % Default value for ploting (curve + points)
        global_show_option = 'C';   % Default value for function ploting (show only current function)
        
%         Default values for function structure
        def_function_name = "New function";
        def_function_points_amount = 0;
        def_function_data = {};
        def_function_degree = '1';
    end
    
    methods (Access = private)
% ////////////////////////////////////////////////////////////////////////
% ///////////////////////     PROMPT FUNCTIONS     ///////////////////////
% ////////////////////////////////////////////////////////////////////////
        
        function log_event(~, msg_type, msg) 
%       Formating: DD.MM.YYYY, HH:MM:SS | MSG_TYPE: MSG
%       Get current date and time
        date_time = datestr(now,'yyyy-mm-dd, HH:MM:SS'); % char
        fileID = fopen("app.log", 'a+'); % open or create file app.log for writing at the end
        switch msg_type
            case 'E'
                fprintf(fileID, '%s | ERROR:   %s\n', date_time, msg);
            case 'W'
                fprintf(fileID, '%s | WARNING: %s\n', date_time, msg);
            case 'I'
                fprintf(fileID, '%s | INFO:    %s\n', date_time, msg);
        end % switch
        fclose(fileID);
        end % log_event()

        function ResetPrompt(app)
            PrintPrompt(app, 'I', "Idle ...");
            log_event(app, 'I', "App prompt reset succesfully.");
        end % ResetPrompt function

        function PrintPrompt(app, msg_type, msg)
            switch msg_type
                case 'I'                % INFO
                    set(app.Prompt, ...
                        'Value', convertStringsToChars(append('INFO: ', msg)), ...
                        'FontColor', '#0000ff');
                case 'W'                % WARNING
                    set(app.Prompt, ...
                        'Value', convertStringsToChars(append('WARNING: ', msg)), ...
                        'FontColor', '#ffff00');
                case 'E'                % ERROR
                    set(app.Prompt, ...
                        'Value', convertStringsToChars(append('ERROR: ', msg)), ...
                        'FontColor', '#ff0000');
            end % switch  
        end % PrintPrompt function
        
% ////////////////////////////////////////////////////////////////////////
% ////////////////////////     AXES FUNCTIONS     ////////////////////////
% ////////////////////////////////////////////////////////////////////////

        function ClearAxes(app)
            plot(app.UIAxes, 0, 0);
            log_event(app, 'I', "Axes was cleared sucesfully.");
        end % ClearAxes function
        
% ////////////////////////////////////////////////////////////////////////
% //////////////////////     PLOTING FUNCTIONS     ///////////////////////
% ////////////////////////////////////////////////////////////////////////
        
        function PlotCurrentFunction(app)
%             Convert table to matrix
            if (class(app.UITable.Data) ~= 'cell') %#ok<BDSCA>
                msg = convertStringsToChars(append('PlotCurrentFunction(app): Cannot plot current function due to wrong class of app.UITable.Data. Recived: ', class(app.UITable.Data), '; Expected: cell.'));
                log_event(app, 'E', msg);
                return;
            else
                matrix = cell2mat(app.UITable.Data);
            end % if
%             Get polynomial degree and check if it's numeric data type
            polynomial_approximation_degree = GetDegree(app, get(app.DegreeDDM, 'Value'));
            if (~(isnumeric(polynomial_approximation_degree)))
                msg = convertStringsToChars(append('PlotCurrentFunction(app): Recived wrong class of app.DegreeDDM, as regression degree is set value 1. Recived: ', class(polynomial_approximation_degree), '; Expected: double.'));
                log_event(app, 'W', msg);
                PrintPrompt(app, 'W', "Setting degree value (1). See log for more details.");
                polynomial_approximation_degree = 1;
            end  
%             Get regression coefficients
            polynomial_approximation_coefficients = polyfit(matrix(:,1), matrix(:,2), polynomial_approximation_degree);                                 
%             function = points, function1 = approximation curve
            x = matrix(:,1);
            y = matrix(:,2);
            x1 = min(matrix(:,1)) - 1 : 0.1 : max(matrix(:,1)) + 1;
            y1 = polyval(polynomial_approximation_coefficients, x1);
%             Plot data
            switch app.show_option
                case 'CP' % curve and points
                    plot(app.UIAxes, x1, y1, x, y, 'o');
                case 'C'  % just curve
                    plot(app.UIAxes, x1, y1);
                case 'P'  % just points
                    plot(app.UIAxes, x, y, 'o');
             end % switch
             set(app.UIAxes.Title, 'String', string(app.FunctionName.Value));
             log_event(app, 'I', "PlotCurrentFunction(app): Current function plotted succesfully.");
        end % PlotCurrentFunction

        function PlotData(app)
% TODO: Popup msgbox to set name for all functions graph

            switch app.global_show_option
                case 'C' % only current 
                    PlotCurrentFunction(app);
                case 'A' % all functions
%                     Plot current function
                    PlotCurrentFunction(app);
%                     Plot functions from database
                    for i = 1:size(app.function_database, 2)
                        hold(app.UIAxes, "on");
                        matrix = cell2mat(num2cell(app.function_database(i).Data));
                        polynomial_approximation_degree = GetDegree(app, app.function_database(i).Degree);
                        polynomial_aproximation_coefficients = polyfit(matrix(:,1), matrix(:,2), polynomial_approximation_degree);
                        x = matrix(:,1);
                        y = matrix(:,2);
                        x1 = min(matrix(:,1)) - 1 : 0.1 : max(matrix(:,1)) + 1;
                        y1 = polyval(polynomial_aproximation_coefficients, x1);
                        switch app.show_option
                            case 'CP'
                                plot(app.UIAxes, x1, y1, x, y, 'o');
                            case 'C'
                                plot(app.UIAxes, x1, y1);
                            case 'P'
                                plot(app.UIAxes, x, y, 'o');
                        end % switch
                        hold(app.UIAxes, "off");
                    end % for
            end % switch
        end % PlotData

% ////////////////////////////////////////////////////////////////////////
% ///////////////////////     DATA CONTROLLER     ////////////////////////
% ////////////////////////////////////////////////////////////////////////
                
        function degree = GetDegree(~, char_value)
            switch char_value
                case '1'
                    degree = 1;
                case '2'
                    degree = 2;
                case '3'
                    degree = 3;
                case '4'
                    degree = 4;
                case '5'
                    degree = 5;
            end % switch
        end % GetDegree function
        
% ////////////////////////////////////////////////////////////////////////
% //////////////////////     DATABASE FUNCTIONS     //////////////////////
% ////////////////////////////////////////////////////////////////////////

        function RefreshFunctionList (app)
            if (isempty(app.function_database))
                log_event(app, 'I', "RefreshFunctionList(app): Function database is empty. Nothing to show.");
                set(app.FunctionList, 'Value', '');
                return;
            end % if
            names_array = char(app.function_database.Name);
            new_array = [];
            for i = 1:size(names_array, 1) 
                old_array = [new_array, names_array(i,:)];
                new_array = [old_array, newline];
            end % for
            set(app.FunctionList, 'Value', new_array);
            log_event(app, 'I', "RefreshFunctionList(app): Function list was refreshed successfully.");
        end % RefreshFunctionList
    end % methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            log_event(app, 'I', "---[ SESSION STARTED ]---");
        end

        % Value changed function: PointSPN
        function Point_ValueChanged(app, event)
%             Get variables value
            point_amount = app.PointSPN.Value;               % Current spinner value
            previous_point_amount = event.PreviousValue;    % Spinner value before change   

%             Increment
            if (point_amount > previous_point_amount) 
                default_values = {0, 0};
                old_data = get(app.UITable, 'data');    % Save table
                new_data = [old_data;default_values];   % New coordinates = previous coordinates & default coordinates
                set(app.UITable, 'data', new_data);     % Add new coordinates to table
                msg = convertStringsToChars(append('Point_ValueChanged(app, event): Number of points was incremented to value: ', num2str(app.PointSPN.Value)));
                log_event(app, 'I', msg);

%              Decrement
            elseif (point_amount < previous_point_amount)
                app.UITable.Data((previous_point_amount), :) = [];  % Delete last row from table
                msg = convertStringsToChars(append('Point_ValueChanged(app, event): Number of points was decremented to value: ', num2str(app.PointSPN.Value)));
                log_event(app, 'I', msg);

%              Unexpected state
            else
                log_event(app, 'E', "Point_ValueChanged(app, event): Unexpected state of changing value.");
                return;
            end % if
            if (app.PointSPN.Value >= 1)
                Refresh_Pushed(app, event);
            end % if
        end

        % Button pushed function: RefreshBTN
        function Refresh_Pushed(app, event)
%             Reset prompt
            ResetPrompt(app);
            ClearAxes(app);
            RefreshFunctionList(app);
            set(app.UIAxes.Title, 'String', string(app.FunctionName.Value));
            
%             Check piont amount spinner
            if (get(app.PointSPN, 'Value') == 0)     % Value is set to zero - no data
                log_event(app, 'W', "RefreshPointsButton(app, event): Number of points is set to zero - nothing to plot");
                PrintPrompt(app, 'W', "Point amount is set to zero, no data to display.");
                plot(app.UIAxes, 0, 0);
                return;
            elseif (get(app.PointSPN, 'Value') < 0) % Value is less than zero
                log_event(app, 'E', "RefreshPointsButton(app, event): Internal app error, number of points is negative number.");
                PrintPrompt(app, 'E', "Point amount cannot be negative number.");
                return;
            end % if

            PlotData(app);
        end

        % Selection changed function: Show
        function ShowSelection_Changed(app, event)
%             Plot only points
            if (get(app.PointsBTN, 'Value') == true)
                app.show_option = 'P';
                log_event(app, 'I', "ShowSelectionCHanged(app, event): Show option changed to 'show only points.'");
%             Plot only curve
            elseif (get(app.CurveBTN, 'Value') == true)
                app.show_option = 'C';
                log_event(app, 'I', "ShowSelectionCHanged(app, event): Show option changed to 'show only curve.'");
%             Plot points & curve
            elseif (get(app.BothBTN, 'Value') == true)
                app.show_option = 'CP';
                log_event(app, 'I', "ShowSelectionCHanged(app, event): Show option changed to 'show curve & points.'");
            else
                PrintPrompt(app, 'E', "Cannot get value from show options.");
                log_event(app, 'E', "ShowSelection_Changed(app, event): Internal app error, unexpected state of show option.");
                return;
            end %
            Refresh_Pushed(app, event);
        end

        % Button pushed function: ImportBTN
        function Import_Pushed(app, event)
%             Select .xlsx file
            [file_name, file_path] = uigetfile({'*.xlsx'}, 'Select a file', 'C:\Documents\');
            if (isequal(file_name, 0))
                log_event(app, 'W', "Import_Pushed(app, event): File selection for import data was canceled by user.");
                PrintPrompt(app, 'W', "User did not selected file, no data imported.")
                return;
            elseif (isequal(file_path, 0))
                log_event(app, 'E', "Import_Pushed(app, event): Failed getting path of imported file.");
                PrintPrompt(app, 'E', "Failed getting file path, no data imported.");
                return;
            end % if
            figure(app.UIFigure); % Reopen window after it's minimalised

%             Set 'file' as string where is stored full path name of file
            file = fullfile(file_path, file_name);

%             Read file
            file_data = readtable(file);
            
%             Copy data to app table
            vars = {'X'; 'Y'};
            file_data = file_data(:, vars);
            set(app.UITable, 'Data', file_data);

%             Convert from table to cell array and import to UITable
            table = app.UITable.Data;
            cell_array = table2cell(table);
            set(app.UITable, 'Data', cell_array);

%             Set spinner value according to imported data and set points
%             amount spinner
            matrix = cell2mat(cell_array);
            set(app.PointSPN, 'Value', size(matrix, 1));

%             Send data to ploting function
            Refresh_Pushed(app,event);
            log_event(app, 'I', "Import_Pushed(app, event): Data imported successfully.");
        end

        % Button pushed function: ClearBTN
        function Clear_Pushed(app, event)
%             Set default value for table data
            app.UITable.Data = app.def_function_data;
            app.PointSPN.Value = app.def_function_points_amount;
%             Clear all from app axes
            ClearAxes(app);
        end

        % Value changed function: DegreeDDM
        function Degree_ValueChanged(app, event)
            Refresh_Pushed(app, event);
           msg = convertStringsToChars(append('DegreeValuePushed(app, event): Regression degree changed to: ', app.DegreeDDM.Value));
           log_event(app, 'I', msg);
        end

        % Button pushed function: Save
        function Save_Pushed(app, event)
%             Check if function is not empty
            if (app.PointSPN.Value <= 0)
                log_event(app, 'E', "Save_Pushed(app, event): User tried to save empty function.");
                PrintPrompt( app, 'E', "Function saving failed. Cannot save empty function.");
                return;
            end
            if (isempty(char(app.FunctionName.Value)))
                set(app.FunctionName, 'Value', app.def_function_name);
            end

%             Create function structure
            function_structure = struct( ...
                'ID', app.selected_function, ...
                'Name', char(app.FunctionName.Value), ...
                'Points_Amount', app.PointSPN.Value,...
                'Data', cell2mat(app.UITable.Data), ...
                'Degree', app.DegreeDDM.Value);
            

%             Save function to database
%             Function is new
            if (size(app.function_database) < function_structure.ID)
                app.function_database = [app.function_database, function_structure];
                PrintPrompt(app, 'I', "Function saved succefully.");
                msg = convertStringsToChars(append('Save_Pushed(app, event): Function ', string(app.FunctionName.Value), ' saved succefully.'));
                log_event(app, 'I', msg);
                RefreshFunctionList(app);

%             Function already exists - overwrite
            elseif (app.function_database(function_structure.ID).ID == function_structure.ID)
                app.function_database(function_structure.ID) = function_structure;
                PrintPrompt(app, 'I', "Function overwrited succefully.");
                msg = convertStringsToChars(append('Save_Pushed(app, event): Function ', string(app.FunctionName.Value), ' overwrited succefully.'));
                log_event(app, 'I', msg);
                RefreshFunctionList(app);

%             Unexpected state
            else
                PrintPrompt(app, 'E', "Saving function failed.");
                msg = convertStringsToChars(append('Save_Pushed(app, event): Internal app error. Failed saving function ', string(app.FunctionName.Value)));
                log_event(app, 'E', msg); 
                return;
            end % if
            set(app.Next, 'Enable', true);
        end

        % Button pushed function: Next
        function Next_Pushed(app, event)
            new_function = true;
            set(app.Previous, 'Enable', true);

%             Increment function selection index
            app.selected_function = app.selected_function + 1;

%             Set default values
            set(app.FunctionName, 'Value', app.def_function_name);
            set(app.UIAxes.Title, 'String', string(app.FunctionName.Value));
            set(app.PointSPN, 'Value', app.def_function_points_amount);
            set(app.UITable, 'Data', app.def_function_data);
            set(app.DegreeDDM, 'Value', app.def_function_degree);
            ClearAxes(app);

%             If function already exists - load from database
            for i = 1 : size(app.function_database, 2)
                if (app.function_database(i).ID == app.selected_function)
                    set(app.FunctionName, 'Value', app.function_database(i).Name);
                    set(app.PointSPN, 'Value', app.function_database(i).Points_Amount);
                    set(app.UITable, 'Data', num2cell(app.function_database(i).Data));
                    set(app.DegreeDDM, 'Value', app.function_database(i).Degree);
                    msg = convertStringsToChars(append('Next_Pushed(app, event): Function ', string(app.FunctionName.Value), ' loaded succesfully.'));
                    log_event(app, 'I', msg);
                    Refresh_Pushed(app, event);
                    return;
                end
            end

            if (new_function == true)
                log_event(app, 'I', "NextButtonPushed(app, event): New function iinitialized seccesfully.")
                set(app.Next, 'Enable', false);
            end

        end

        % Button pushed function: Previous
        function Previous_Pushed(app, event)
            if (app.selected_function == 2)
                set(app.Previous, 'Enable', false);           
            end
            app.selected_function = app.selected_function - 1;
            for i = 1 : size(app.function_database, 2)
                if (app.function_database(i).ID == app.selected_function)
                    set(app.FunctionName, 'Value', app.function_database(i).Name);
                    set(app.PointSPN, 'Value', app.function_database(i).Points_Amount);
                    set(app.UITable, 'Data', num2cell(app.function_database(i).Data));
                    set(app.DegreeDDM, 'Value', app.function_database(i).Degree);
                    msg = convertStringsToChars(append('Previous_Pushed(app, event): Function ', string(app.FunctionName.Value), ' loaded succesfully.'));
                    log_event(app, 'I', msg);
                    Refresh_Pushed(app,event);
                    set(app.Next, 'Enable', true);
                    return;
                end
            end
        end

        % Selection changed function: FunctionsBTN
        function FunctionsSelection_Changed(app, event)
            if (get(app.All, 'Value') == true)
                app.global_show_option = 'A';
                log_event(app, 'I', "FunctionsButtonGroupSelectionChanged(app, event): Global show f option has changed to 'show all functions'.");
            elseif (get(app.Current, 'Value') == true)
                app.global_show_option = 'C';
                log_event(app, 'I', "FunctionsButtonGroupSelectionChanged(app, event): Global show f option has changed to 'show only current function'.");
            else 
                % Internal app error
            end
            Refresh_Pushed(app, event);
        end

        % Close request function: UIFigure
        function UIFigure_CloseRequest(app, event)
            log_event(app, 'I', "---[ SESSION ENDED ]---");
            delete(app)
            
        end

        % Button pushed function: Delete
        function Delete_Pushed(app, event)
%             Confirm delete function
            conf = questdlg(convertCharsToStrings(append('Are You sure You want to delete function ', app.FunctionName.Value)), ...
                'Delete function', 'Yes', 'Cancel', 'Cancel');
            switch conf
                case 'Yes'
                    
                case 'Cancel'
                    return;
            end
            
            % Check if current function is really in the database
            if (isempty(app.function_database) || app.selected_function > size(app.function_database, 2))
                PrintPrompt(app, 'E', "Cannot delete unsaved function.");
                msg = convertStringsToChars(append('DeletePush(app, event): Atempt to delete selected function failed due to the current function is unsaved.'));
                log_event(app, 'E', msg);
                return;
            elseif (app.function_database(app.selected_function).ID == app.selected_function)
                deleted_function_name = string(app.FunctionName.Value);
                app.function_database(app.selected_function) = [];                  % Delete function from the database
%                 Refresh indexes of all functions
                for i = 1 : size(app.function_database, 2)
                    app.function_database(i).ID = i;
                end
%                 if deleted function was last saved
                set(app.FunctionName, 'Value', app.def_function_name);
                set(app.PointSPN, 'Value', app.def_function_points_amount);
                set(app.UITable, 'Data', app.def_function_data);
                set(app.DegreeDDM, 'Value', app.def_function_degree);
                ClearAxes(app);
                set(app.Next, 'Enable', false)
%                 If exists function with higher ID then had the deleted
%                 function
                for i = 1 : size(app.function_database, 2)
                    if (app.function_database(i).ID == app.selected_function)
                        set(app.FunctionName, 'Value', app.function_database(i).Name);
                        set(app.PointSPN, 'Value', app.function_database(i).Points_Amount);
                        set(app.UITable, 'Data', num2cell(app.function_database(i).Data));
                        set(app.DegreeDDM, 'Value', app.function_database(i).Degree);
                        set(app.Next, 'Enable', true)
                        Refresh_Pushed(app, event);
                        msg = convertStringsToChars(append('Delete_Pushed(app, event): Function ', deleted_function_name, ' was deleted successfully.'));
                        log_event(app, 'I', msg);
                        return;
                    end
                end
                if (app.selected_function > size(app.function_database))
                    Previous_Pushed(app, event);
                end
                Refresh_Pushed(app, event);
                msg = convertStringsToChars(append('Delete_Pushed(app, event): Function ', deleted_function_name, ' was deleted successfully.'));
                log_event(app, 'I', msg);
            else
                PrintPrompt(app, 'E', "Cannot delete unsaved function.");
                msg = convertStringsToChars(append('DeletePush(app, event): Atempt to delete selected function failed due to the current function is unsaved.'));
                log_event(app, 'E', msg);
                return;
            end
        end

        % Button pushed function: toEXCEL
        function toEXCEL_Pushed(app, event)
%             Check if template is reachable and make a copy
            if ~isfile('export_to_excel_template.xlsx')
                PrintPrompt(app, 'E', "Cannot export. Template file is coruppted.");
                log_event(app, 'E', "toExcelButtonPushed(app, event): Cannot export file due to missing template file in binaries.");
                return;
            end
            export_file = ['carl_export', '.xlsx'];
            copyfile("export_to_excel_template.xlsx", export_file);
            
%             Write data into file
            writecell(app.FunctionName.Value, export_file, 'Range', 'F3', 'AutoFitWidth',false);
            writematrix(cell2mat(app.UITable.Data), export_file, 'Range', 'A4', 'AutoFitWidth',false);
            writematrix(GetDegree(app, get(app.DegreeDDM, 'Value')), export_file, 'Range', 'F5', 'AutoFitWidth',false);

            %             Convert table to matrix
            if (class(app.UITable.Data) ~= 'cell') %#ok<BDSCA>
                msg = convertStringsToChars(append('PlotCurrentFunction(app): Cannot plot current function due to wrong class of app.UITable.Data. Recived: ', class(app.UITable.Data), '; Expected: class.'));
                log_event(app, 'E', msg);
                return;
            else
                matrix = cell2mat(app.UITable.Data);
            end % if
%             Get polynomial degree and check if it's numeric data type
            polynomial_approximation_degree = GetDegree(app, get(app.DegreeDDM, 'Value'));
            if (~(isnumeric(polynomial_approximation_degree)))
                msg = convertStringsToChars(append('PlotCurrentFunction(app): Recived wrong class of app.DegreeDDM, as regression degree is set value 1. Recived: ', class(polynomial_approximation_degree), '; Expected: double.'));
                log_event(app, 'W', msg);
                PrintPrompt(app, 'W', "Setting degree value (1). See log for more details.");
                polynomial_approximation_degree = 1;
            end  
%             Get regression coefficients
            polynomial_approximation_coefficients = polyfit(matrix(:,1), matrix(:,2), polynomial_approximation_degree);
            switch GetDegree(app, get(app.DegreeDDM, 'Value'))
                case 1
                    fnc = append('y = (', num2str(polynomial_approximation_coefficients(1)), ')x + (', ...
                                          num2str(polynomial_approximation_coefficients(2)), ')');
                    writematrix(fnc, export_file, 'Range', 'F7', 'AutoFitWidth',false);
                case 2
                    fnc = append('y = (', num2str(polynomial_approximation_coefficients(1)), ')x^2 + (', ...
                                          num2str(polynomial_approximation_coefficients(2)), ')x + (', ...
                                          num2str(polynomial_approximation_coefficients(3)), ')');
                    writematrix(fnc, export_file, 'Range', 'F7', 'AutoFitWidth',false);
                case 3
                    fnc = append('y = (', num2str(polynomial_approximation_coefficients(1)), ')x^3 + (', ...
                                          num2str(polynomial_approximation_coefficients(2)), ')x^2 + (', ...
                                          num2str(polynomial_approximation_coefficients(3)), ')x + (', ...
                                          num2str(polynomial_approximation_coefficients(4)), ')');
                    writematrix(fnc, export_file, 'Range', 'F7', 'AutoFitWidth',false);
                case 4
                    fnc = append('y = (', num2str(polynomial_approximation_coefficients(1)), ')x^4 + (', ...
                                          num2str(polynomial_approximation_coefficients(2)), ')x^3 + (', ...
                                          num2str(polynomial_approximation_coefficients(3)), ')x^2 + (', ...
                                          num2str(polynomial_approximation_coefficients(4)), ')x + (', ...
                                          num2str(polynomial_approximation_coefficients(5)), ')');
                    writematrix(fnc, export_file, 'Range', 'F7', 'AutoFitWidth',false);
                case 5
                    fnc = append('y = (', num2str(polynomial_approximation_coefficients(1)), ')x^5 + (', ...
                                          num2str(polynomial_approximation_coefficients(2)), ')x^4 + (', ...
                                          num2str(polynomial_approximation_coefficients(3)), ')x^3 + (', ...
                                          num2str(polynomial_approximation_coefficients(4)), ')x^2 + (', ...
                                          num2str(polynomial_approximation_coefficients(5)), ')x + (', ...
                                          num2str(polynomial_approximation_coefficients(5)), ')');
                    writematrix(fnc, export_file, 'Range', 'F7', 'AutoFitWidth',false);
            end 
            
            [file, path] = uiputfile('*.xlsx');
            filename = fullfile(path,file);
            copyfile (export_file, filename);
            delete export_file;


        end

        % Value changed function: FunctionName
        function FunctionName_Changed(app, event)
            if (app.FunctionName.Value == "Standa Vitek")
                Clear_Pushed(app, event);
                file = "Vitek.xlsx";
                file_data = readtable(file);

                vars = {'X'; 'Y'};
                file_data = file_data(:, vars);
                set(app.UITable, 'Data', file_data);

                table = app.UITable.Data;
                cell_array = table2cell(table);
                set(app.UITable, 'Data', cell_array);

                matrix = cell2mat(cell_array);
                set(app.PointSPN, 'Value', size(matrix, 1));
                Refresh_Pushed(app,event);
            end
            set(app.UIAxes.Title, 'String', string(app.FunctionName.Value));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {601, 601};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigure_CloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SpinnerLabel = uilabel(app.LeftPanel);
            app.SpinnerLabel.Text = 'Points Amount';
            app.PointSPN = uispinner(app.LeftPanel);
            app.PointSPN.Limits = [0 Inf];
            app.PointSPN.ValueChangedFcn = createCallbackFcn(app, @Point_ValueChanged, true);
            app.PointSPN.Editable = 'off';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.RefreshBTN = uibutton(app.LeftPanel, 'push');
            app.RefreshBTN.ButtonPushedFcn = createCallbackFcn(app, @Refresh_Pushed, true);
            app.RefreshBTN.Text = 'Refresh points';
            app.ImportBTN = uibutton(app.LeftPanel, 'push');
            app.ImportBTN.ButtonPushedFcn = createCallbackFcn(app, @Import_Pushed, true);
            app.ImportBTN.Text = 'Import Data';
            app.ClearBTN = uibutton(app.LeftPanel, 'push');
            app.ClearBTN.ButtonPushedFcn = createCallbackFcn(app, @Clear_Pushed, true);
            app.ClearBTN.Text = 'Clear';
            app.DegreeofpolynomialDropDownLabel = uilabel(app.LeftPanel);
            app.DegreeofpolynomialDropDownLabel.Text = 'Degree of polynomial';
            app.DegreeDDM = uidropdown(app.LeftPanel);
            app.DegreeDDM.Items = {'1', '2', '3', '4', '5'};
            app.DegreeDDM.ValueChangedFcn = createCallbackFcn(app, @Degree_ValueChanged, true);
            app.DegreeDDM.Value = '1';
            app.FxController = uipanel(app.LeftPanel);
            app.FxController.Title = 'Function controller';
            app.NameTextAreaLabel = uilabel(app.FxController);
            app.NameTextAreaLabel.Text = 'Name:';
            app.FunctionName = uitextarea(app.FxController);
            app.FunctionName.ValueChangedFcn = createCallbackFcn(app, @FunctionName_Changed, true);
            app.FunctionName.Placeholder = 'Enter function name';
            app.FunctionName.Value = {'Function 1'};
            app.Save = uibutton(app.FxController, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @Save_Pushed, true);
            app.Save.Text = 'Save function';
            app.Previous = uibutton(app.FxController, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @Previous_Pushed, true);
            app.Previous.Enable = 'off';
            app.Previous.Text = 'Previous';
            app.Next = uibutton(app.FxController, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @Next_Pushed, true);
            app.Next.Enable = 'off';
            app.Next.Text = 'Next';
            app.Delete = uibutton(app.FxController, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @Delete_Pushed, true);
            app.Delete.Text = 'Delete function';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Show = uibuttongroup(app.RightPanel);
            app.Show.SelectionChangedFcn = createCallbackFcn(app, @ShowSelection_Changed, true);
            app.Show.Title = 'Show';
            app.BothBTN = uiradiobutton(app.Show);
            app.BothBTN.Text = 'Aproximation curve + Points';
            app.BothBTN.Value = true;
            app.CurveBTN = uiradiobutton(app.Show);
            app.CurveBTN.Text = 'Only aproximation curve';
            app.PointsBTN = uiradiobutton(app.Show);
            app.PointsBTN.Text = 'Only points';
            app.PromptTextAreaLabel = uilabel(app.RightPanel);
            app.PromptTextAreaLabel.Text = 'Prompt';
            app.Prompt = uitextarea(app.RightPanel);
            app.Prompt.Editable = 'off';
            app.Prompt.Value = {'Idle ...'};
            app.FunctionsBTN = uibuttongroup(app.RightPanel);
            app.FunctionsBTN.SelectionChangedFcn = createCallbackFcn(app, @FunctionsSelection_Changed, true);
            app.FunctionsBTN.Title = 'Functions';
            app.Current = uiradiobutton(app.FunctionsBTN);
            app.Current.Text = 'Only current function';
            app.Current.Value = true;
            app.All = uiradiobutton(app.FunctionsBTN);
            app.All.Text = 'All functions';
            app.SavedfunctionsTextAreaLabel = uilabel(app.RightPanel);
            app.SavedfunctionsTextAreaLabel.Text = 'Saved functions';
            app.FunctionList = uitextarea(app.RightPanel);
            app.FunctionList.Editable = 'off';
            app.FunctionList.Placeholder = 'No function was saved';
            app.Export = uipanel(app.RightPanel);
            app.Export.Title = 'Export';
            app.toEXCEL = uibutton(app.Export, 'push');
            app.toEXCEL.ButtonPushedFcn = createCallbackFcn(app, @toEXCEL_Pushed, true);
            app.toEXCEL.Text = 'Excel';
            app.toOO = uibutton(app.Export, 'push');
            app.toOO.Enable = 'off';
            app.toOO.Visible = 'off';
            app.toOO.Text = 'OpenOffice';
            app.toPDF = uibutton(app.Export, 'push');
            app.toPDF.Enable = 'off';
            app.toPDF.Visible = 'off';
            app.toPDF.Text = 'PDF';
            app.OpenLogBTN = uibutton(app.RightPanel, 'push');
            app.OpenLogBTN.Text = 'Open log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end